classdef HardwareControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HardwareControlPanelUIFigure  matlab.ui.Figure
        PlSubPanel1                   matlab.ui.container.Panel
        PhaseLock                     matlab.ui.container.Panel
        PlSubPanel2                   matlab.ui.container.Panel
        ShowAllButton_4               matlab.ui.control.Button
        ConnectAllButton_4            matlab.ui.control.Button
        WgSubPanel1                   matlab.ui.container.Panel
        RgSubPanel1                   matlab.ui.container.Panel
        ScopeSubPanel1                matlab.ui.container.Panel
        WaveformGeneratorPanel        matlab.ui.container.Panel
        WgSubPanel2                   matlab.ui.container.Panel
        WaveformListEditorButton      matlab.ui.control.Button
        ShowAllButton                 matlab.ui.control.Button
        ConnectAllButton              matlab.ui.control.Button
        RFGeneratorPanel              matlab.ui.container.Panel
        RgSubPanel2                   matlab.ui.container.Panel
        ShowAllButton_2               matlab.ui.control.Button
        ConnectAllButton_2            matlab.ui.control.Button
        ScopePanel                    matlab.ui.container.Panel
        ScopeSubPanel2                matlab.ui.container.Panel
        ShowAllButton_3               matlab.ui.control.Button
        ConnectAllButton_3            matlab.ui.control.Button
        VariablesPanel                matlab.ui.container.Panel
        RepeatSpinner                 matlab.ui.control.Spinner
        RepeatSpinnerLabel            matlab.ui.control.Label
        AutoShuffleCheckBox           matlab.ui.control.CheckBox
        UploadButton                  matlab.ui.control.Button
        TriggerSourceButtonGroup      matlab.ui.container.ButtonGroup
        ManualButton                  matlab.ui.control.RadioButton
        BecExpButton                  matlab.ui.control.RadioButton
        ScanButton                    matlab.ui.control.Button
        LockVariableButton            matlab.ui.control.Button
        ScanMethodButtonGroup         matlab.ui.container.ButtonGroup
        CrossButton                   matlab.ui.control.RadioButton
        DotButton                     matlab.ui.control.RadioButton
        EditListDropDown              matlab.ui.control.DropDown
        EditListLabel                 matlab.ui.control.Label
        AddVariableButton             matlab.ui.control.Button
        Panel_3                       matlab.ui.container.Panel
        VarGrid                       matlab.ui.container.GridLayout
    end


    properties (Access = public)
        HardwareList % Store hardware's Name and Type
        RunningWgControl WgControlPanel
        RunningScopeControl ScopeControlPanel
        RunningPhaseLockControl PhaseLockControlPanel
        VariableList table % Store variable's Name, Value, List, Equation and EquationValue
        ListList table % Store Lists for scan
        ScanList table % Store variable's Name, Value, List, Equation, EquationValue and ListValue
        CurrentRunNumber double = 1
        CurrentVariableList table % Store runtime variable's Name and Value. Updated based on ListValue and Equation.
        IsBecExpScanned logical
        WaveformLibrary WaveformList
        WaveformGeneratorSetting table
        PhaseLockSetting table
        ScopeSetting table
        NWg double
        NScope double
        NPl double
        WgName string
        ScopeName string
        PlName string
    end
    
    properties (Access = private)
        IsEquationCheck logical % Description
    end

    properties (Constant)
        VarColumn = 5
        VarPrefix = "hw_"
        EquationDepth = 5
    end

    methods (Access = private)

        function addVar(app,name,value,list)
            name = string(name);
            list = string(list);
            app.VarGrid.RowHeight{end + 1} = 20;
            uieditfield(app.VarGrid,'text','Value',name,'ValueChangedFcn',{@app.nameChanged});
            v = uieditfield(app.VarGrid, 'numeric','Value',value,'ValueChangedFcn',{@app.valueChanged});
            l = uidropdown(app.VarGrid,'Items',{'None','List1','List2','List3','List4'},...
                'Value',list,'ValueChangedFcn',{@app.listDropDownChanged});
            eq = uibutton(app.VarGrid, 'push','Text','Equation','ButtonPushedFcn',{@app.equationButtonPress});
            uibutton(app.VarGrid, 'push','Text','Delete','ButtonPushedFcn',{@app.deleteButtonPress});
            if list ~= "None"
                v.Enable = "off";
                eq.Enable = "off";
            end
            if app.VariableList(app.VariableList.Name == name,:).Equation ~= ""
                v.Enable = "off";
                l.Enable = "off";
            end
        end

        function listDropDownChanged(app,src,~)
            row = src.Layout.Row;
            app.VariableList.List(row) = src.Value;
            app.buildScanList;
            app.evaluateEquation;
            if string(src.Value) ~= "None"
                app.VarGrid.Children(row*app.VarColumn - 1).Enable = "off";
                app.VarGrid.Children(row*app.VarColumn - 3).Enable = "off";
            else
                app.VarGrid.Children(row*app.VarColumn - 1).Enable = "on";
                app.VarGrid.Children(row*app.VarColumn - 3).Enable = "on";
            end
        end

        function deleteButtonPress(app,src,~)
            nRow = numel(app.VarGrid.RowHeight);
            nColumn = app.VarColumn;
            row = src.Layout.Row;
            varName = string(app.VarGrid.Children((row-1)*nColumn + 1).Value);
            msg = app.checkVariableBinding(varName);
            if msg ~= ""
                msg = msg + "Please resolve then delete.";
                msgbox(msg);
                return
            else
                answer = questdlg("Do you want to delete the variable [" + varName + "]?", ...
                	'Confirm', ...
                    'Yes','No','No');
                if string(answer) == "No"
                    return
                end
            end
            for ii = (row+1):nRow
                for jj = 1:nColumn
                    app.VarGrid.Children((ii-1)*nColumn + jj).Layout.Row = ...
                        app.VarGrid.Children((ii-1)*nColumn + jj).Layout.Row - 1;
                end
            end
            permuteIdx = [1:(row-1)*nColumn,(row*nColumn+1):nRow*nColumn,((row-1)*nColumn+1):row*nColumn];
            app.VarGrid.Children = app.VarGrid.Children(permuteIdx);
            delete(app.VarGrid.Children((end-nColumn+1) : end))
            app.VarGrid.RowHeight(end) = [];
            app.VariableList(row,:) = [];
            app.buildScanList
            app.evaluateEquation
        end

        function equationButtonPress(app,src,~)
            row = src.Layout.Row;
            nColumn = app.VarColumn;
            eq = app.VariableList(row,:).Equation;
            name = app.VariableList(row,:).Name;
            if isempty(eq)
                answer = inputdlg("Enter equation for [" + name + "]","Equation",[6,30]);
            else
                answer = inputdlg("Enter equation for [" + name + "]","Equation",[6,30],eq);
            end
            if size(answer,1) == 0
                return
            end
            if isempty(answer)
                answer = "";
            end
            answer = string(answer);
            app.VariableList(row,:).Equation = answer;
            if answer == ""
                app.VariableList.EquationValue(row) = app.VariableList.Value(row);
                app.VarGrid.Children((row-1)*nColumn + 2).Value = app.VariableList.Value(row);
                app.VarGrid.Children(row*nColumn - 2).Enable = "on";
                app.VarGrid.Children(row*nColumn - 3).Enable = "on";
            else
                app.VarGrid.Children(row*nColumn - 2).Enable = "off";
                app.VarGrid.Children(row*nColumn - 3).Enable = "off";
            end
            app.evaluateEquation
        end

        function row = findRow(app,name)
            names = app.VariableList.Name;
            row = find(names == name);
        end

        function valueChanged(app,src,~)
            row = src.Layout.Row;
            app.VariableList.Value(row) = src.Value;
            app.buildScanList;
            app.evaluateEquation;
        end

        function nameChanged(app,src,evt)
            row = src.Layout.Row;
            name = string(src.Value);
            nameOld = string(evt.PreviousValue);

            % Add prefix
            if ~startsWith(name,app.VarPrefix)
                name = app.VarPrefix + name;
            end

            % Check name conflicts
            if any(app.VariableList.Name == name)
                warning("[" + name + "] has been used.")
                src.Value = app.VariableList.Name(row);
                return
            end

            % Rename
            app.VariableList.Name(row) = name;
            src.Value = name;

            % Rename for WaveformLibrary
            WaveformLibrary = app.WaveformLibrary;
            for ii = 1:numel(WaveformLibrary)
                for jj = 1:numel(WaveformLibrary(ii).WaveformOrigin)
                    scan = WaveformLibrary(ii).WaveformOrigin{jj}.Scan;
                    if ~isempty(scan)
                        scan.VariableName = replace(scan.VariableName,nameOld,name);
                        WaveformLibrary(ii).WaveformOrigin{jj}.Scan = scan;
                    end
                end
            end
            app.WaveformLibrary = WaveformLibrary;
            save(which("WaveformLibrary.mat"),"WaveformLibrary");

            % Rename for PhaseLock
            % PhaseLockSetting = app.PhaseLockSetting;
            % PhaseLockSetting.VariableName = replace(PhaseLockSetting.VariableName,nameOld,name);
            % app.PhaseLockSetting = PhaseLockSetting;
            % save(which("PhaseLockSetting.mat"),"PhaseLockSetting");
            % 
            % load Config.mat BecExpConfig
            % for ii = 1:size(BecExpConfig,1)
            %     pla = BecExpConfig.PhaseLockAssociation(ii);
            %     if ~isempty(pla) && ~ismissing(pla) && pla ~="None"
            %         plat = str2table(pla);
            %         plat.VariableName = replace(plat.VariableName,nameOld,name);
            %         BecExpConfig.PhaseLockAssociation(ii) = table2str(plat);
            %     end
            % end
            % save(which("Config.mat"),"BecExpConfig","-append");
        end

        function saveVariable(app)
            VariableList = app.VariableList;
            ListList = app.ListList;
            save(which("HardwareVariable.mat"),'VariableList','ListList')
        end

        function saveSetting(app)
            WaveformGeneratorSetting = app.WaveformGeneratorSetting;
            ScopeSetting = app.ScopeSetting;
            PhaseLockSetting = app.PhaseLockSetting;
            save(which("WaveformGeneratorSetting.mat"),"WaveformGeneratorSetting");
            save(which("ScopeSetting.mat"),"ScopeSetting");
            save(which("PhaseLockSetting.mat"),"PhaseLockSetting");
        end

        function lockVariable(app)
            for ii = 1:numel(app.VarGrid.Children)
                app.VarGrid.Children(ii).Enable = "off";
            end
            app.EditListDropDown.Enable = "off";
            app.AddVariableButton.Enable = "off";
        end

        function unlockVariable(app)
            nColumn = app.VarColumn;
            eqIdx = find(app.VariableList.Equation ~= "");
            listIdx = find(app.VariableList.List ~= "None");
            for ii = 1:numel(app.VarGrid.Children)
                app.VarGrid.Children(ii).Enable = "on";
            end
            if ~isempty(eqIdx)
                [app.VarGrid.Children(eqIdx * nColumn - 3).Enable] = deal("off");
                [app.VarGrid.Children(eqIdx * nColumn - 2).Enable] = deal("off");
            end
            if ~isempty(listIdx)
                [app.VarGrid.Children(listIdx * nColumn - 3).Enable] = deal("off");
                [app.VarGrid.Children(listIdx * nColumn - 1).Enable] = deal("off");
            end
            app.EditListDropDown.Enable = "on";
            app.AddVariableButton.Enable = "on";
        end

        function upload(app)
            % Check equation
            app.evaluateEquation
            if ~app.IsEquationCheck
                error("Can not upload. Please fix equation errors.")
            end

            % Save variable values in to the runtime property CurrentVariableList
            app.CurrentVariableList = app.VariableList(:,[1,5]);
            app.CurrentVariableList = renamevars(app.CurrentVariableList,'EquationValue','Value');
            scanningVar = app.VariableList(app.VariableList.List ~= "None",:).Name;

            % Display
            if ~isempty(scanningVar)
                disp(newline)
                disp("Run #" + num2str(app.CurrentRunNumber) + "(out of " + num2str(size(app.ScanList.ListValue,2)) + " total runs)")
                disp(app.CurrentVariableList(ismember(app.CurrentVariableList.Name,scanningVar),:))
            else
                disp("No variable bound to any lists.")
            end
            if app.CurrentRunNumber == 1
                app.saveSetting
            end

            % Upload to awg
            for ii = 1:numel(app.RunningWgControl)
                app.RunningWgControl(ii).upload;
            end

            % Phase Lock
            if string(app.ScanButton.Text) == "Stop"
                if app.CurrentRunNumber == 1
                    app.lock
                else
                    scanningVar = app.VariableList(app.VariableList.List ~= "None",:).Name;
                    rPl = app.RunningPhaseLockControl;
                    if ~isempty(rPl)
                        for ii = 1:numel(rPl)
                            plVar = string(rPl(ii).VariableDropDown.Value);
                            if ismember(plVar,scanningVar)
                                rPl(ii).lock
                            end
                        end
                    end
                end
            else
                app.lock
            end
        end

        function disableButton(app)
            for ii = 1:numel(app.RunningWgControl)
                if isvalid(app.RunningWgControl(ii))
                    app.RunningWgControl(ii).disableButton;
                end
            end
        end

        function enableButton(app)
            for ii = 1:numel(app.RunningWgControl)
                if isvalid(app.RunningWgControl(ii))
                    app.RunningWgControl(ii).enableButton;
                end
            end
        end
    end

    methods (Access = public)
        function connectButtonPress(app,src,~,name)
            if string(app.ScanButton.Text) == "Stop"
                return
            end
            name = string(name);
            if string(src.Text) == "Connect"
                switch app.HardwareList(app.HardwareList.Name == name,:).Type
                    case "WaveformGenerator"
                        app.RunningWgControl(end+1) = WgControlPanel(name,app);
                        if numel(app.RunningWgControl) == app.NWg
                            app.ConnectAllButton.Text = "Disconnect All";
                        end
                    case "Scope"
                        app.RunningScopeControl(end+1) = ScopeControlPanel(name,app);
                        if numel(app.RunningScopeControl) == app.NScope
                            app.ConnectAllButton_3.Text = "Disconnect All";
                        end
                    case "PhaseLock"
                        app.RunningPhaseLockControl(end+1) = PhaseLockControlPanel(name,app);
                        if numel(app.RunningPhaseLockControl) == app.NPl
                            app.ConnectAllButton_4.Text = "Disconnect All";
                        end
                end
                src.Text = 'Disconnect';
            else
                switch app.HardwareList(app.HardwareList.Name == name,:).Type
                    case "WaveformGenerator"
                        validWg = app.RunningWgControl(isvalid(app.RunningWgControl));
                        validWg([validWg.Name] == name).disconnect
                        app.RunningWgControl = app.RunningWgControl(isvalid(app.RunningWgControl));
                        if isempty(app.RunningWgControl)
                            app.ConnectAllButton.Text = "Connect All";
                        end
                    case "Scope"
                        validScope = app.RunningScopeControl(isvalid(app.RunningScopeControl));
                        validScope([validScope.Name] == name).disconnect
                        app.RunningScopeControl = app.RunningScopeControl(isvalid(app.RunningScopeControl));
                        if isempty(app.RunningScopeControl)
                            app.ConnectAllButton_3.Text = "Connect All";
                        end
                    case "PhaseLock"
                        validPl = app.RunningPhaseLockControl(isvalid(app.RunningPhaseLockControl));
                        validPl([validPl.Name] == name).disconnect
                        app.RunningPhaseLockControl = app.RunningPhaseLockControl(isvalid(app.RunningPhaseLockControl));
                        if isempty(app.RunningPhaseLockControl)
                            app.ConnectAllButton_4.Text = "Connect All";
                        end
                end
                src.Text = 'Connect';
            end
        end

        function showWindowButtonPress(app,~,~,name)
            switch app.HardwareList(app.HardwareList.Name == name,:).Type
                case "WaveformGenerator"
                    validWg = app.RunningWgControl(isvalid(app.RunningWgControl));
                    if ~isempty(validWg)
                        names = [validWg.Name];
                        if any(ismember(names,name))
                            validWg([validWg.Name] == name).WaveformGeneratorControlPanelUIFigure.Visible = 'on';
                        end
                    end
                case "Scope"
                    validScope = app.RunningScopeControl(isvalid(app.RunningScopeControl));
                    if ~isempty(validScope)
                        names = [validScope.Name];
                        if any(ismember(names,name))
                            validScope([validScope.Name] == name).ScopeControlPenelUIFigure.Visible = 'on';
                        end
                    end
                case "PhaseLock"
                    validPl = app.RunningPhaseLockControl(isvalid(app.RunningPhaseLockControl));
                    if ~isempty(validPl)
                        names = [validPl.Name];
                        if any(ismember(names,name))
                            validPl([validPl.Name] == name).PhaseLockControlPanelUIFigure.Visible = 'on';
                        end
                    end
            end
        end

        function readScope(app)
            for ii = 1:numel(app.RunningScopeControl)
                app.RunningScopeControl(ii).read
            end
        end

        function update(app)
            if app.IsBecExpScanned
                nRun = size(app.ScanList.ListValue,2);
                runNumber = app.CurrentRunNumber;
                if runNumber < nRun
                    app.CurrentRunNumber = runNumber + 1;
                    app.upload;
                elseif runNumber == nRun
                    app.ScanButtonPushed;
                end
            end
        end

        function lock(app)
            rPl = app.RunningPhaseLockControl;
            if ~isempty(rPl)
                for ii = 1:numel(rPl)
                    if isvalid(rPl(ii))
                        rPl(ii).lock
                    end
                end
            end
        end

        function unlock(app)
            rPl = app.RunningPhaseLockControl;
            if ~isempty(rPl)
                for ii = 1:numel(rPl)
                    if isvalid(rPl(ii))
                        rPl(ii).unlock
                    end
                end
            end
        end

        function setAssociation(app,options)
            arguments
                app
                options.WaveformAssociationTable = [];
                options.PhaseLockAssociationTable = [];
            end
            wat = options.WaveformAssociationTable;
            plat = options.PhaseLockAssociationTable;
            if isempty(wat) && isempty(plat)
                return
            end

            %% If it's scanning, stop it
            isScan = app.ScanButton.Text == "Stop";
            if isScan
                app.ScanButtonPushed;
            end

            %% Waveform association
            if ~isempty(wat)
                % Force to change Wg Settings
                wgGridChild = app.WgSubPanel1.Children.Children;
                for ii = 1:size(wat,1)
                    wgName = wat.WaveformGeneratorName(ii);
                    chNumber = getNumberFromString(wat.ChannelName(ii));
                    wlName = wat.WaveformListName(ii);
                    rowNumber = find(app.WgName == wgName);

                    % Connect to the Wg if it was not connected
                    if wgGridChild(rowNumber*3-1).Text == "Connect"
                        app.connectButtonPress(wgGridChild(rowNumber*3-1),1,wgName);
                        app.showWindowButtonPress(wgGridChild(rowNumber*3-1),1,wgName);
                    end

                    % Enable the channel if it was not enabled
                    wgApp = app.RunningWgControl([app.RunningWgControl.Name] == wgName);
                    wgApp.WaveformGeneratorControlPanelUIFigure.Visible = 'off';
                    wgApp.setChannelWaveformList(chNumber,wlName);
                end
            end

            %% Phase Lock association
            if ~isempty(plat)
                % Force to change Pl Settings
                plGridChild = app.PlSubPanel1.Children.Children;
                for ii = 1:size(plat,1)
                    plName = plat.PhaseLockName(ii);
                    freq = double(plat.Frequency(ii));
                    varName = plat.VariableName(ii);
                    rowNumber = find(app.PlName == plName);

                    % Connect to the Pl if it was not connected
                    if plGridChild(rowNumber*3-1).Text == "Connect"
                        app.connectButtonPress(plGridChild(rowNumber*3-1),1,plName);
                        app.showWindowButtonPress(plGridChild(rowNumber*3-1),1,plName);
                    end

                    % Hide the window
                    plApp = app.RunningPhaseLockControl([app.RunningPhaseLockControl.Name] == plName);
                    plApp.PhaseLockControlPanelUIFigure.Visible = 'off';

                    % Set the lock frequency
                    plApp.PhaseLock.Frequency = freq;
                    plApp.PhaseLock.VariableName = varName;
                    plApp.updateDisplay;
                end
            end

            %% Restart scan or upload
            if isScan
                app.ScanButtonPushed;
            else
                app.UploadButtonPushed;
            end

        end
        
        function dispMsg = checkVariableBinding(app,varName)
            wfl = app.WaveformLibrary;
            dispMsg = "";
            for ii = 1:numel(wfl)
                for jj = 1:numel(wfl(ii).WaveformOrigin)
                    scan = wfl(ii).WaveformOrigin{jj}.Scan;
                    if ~isempty(scan)
                        if any(scan.VariableName == varName)
                            dispMsg = dispMsg + "WaveformList [" + wfl(ii).Name + ...
                                "] is binded to Variable [" + varName + "]" + newline;
                        end
                    end
                end
            end
            load Config.mat BecExpConfig
            for ii = 1:size(BecExpConfig,1)
                pla = BecExpConfig.PhaseLockAssociation(ii);
                if ~isempty(pla) && ~ismissing(pla) && pla ~="None"
                    plat = str2table(pla);
                    if any(plat.VariableName == varName)
                        dispMsg = dispMsg + "Trial [" + BecExpConfig.TrialName(ii) + ...
                            "] is binded to Variable [" + varName + "]" + newline;
                    end
                end
            end
        end
        
        function evaluateEquation(app)
            nColumn = app.VarColumn;
            nRow = size(app.VariableList,1);
            eqIdx = find(app.VariableList.Equation ~= "");
            neqIdx = find(~(app.VariableList.Equation ~= ""));

            % Toggle gui components
            if ~isempty(neqIdx)
                [app.VarGrid.Children((neqIdx-1)*nColumn + 4).BackgroundColor] = deal([0.96,0.96,0.96]);
            end
            if ~isempty(eqIdx)
                [app.VarGrid.Children((eqIdx-1)*nColumn + 4).BackgroundColor] = deal([0.3922 0.8314 0.0745]);
            end

            % Rewrite equatioons so we can evaluate them
            eq = app.VariableList.Equation(eqIdx);
            if isempty(app.ScanList)
                val = app.VariableList.Value;
            else
                val = app.ScanList.ListValue(:,app.CurrentRunNumber);
            end
            val(eqIdx) = deal(NaN);
            [~,idx] = sort(strlength(app.VariableList.Name),'descend');
            eq = replace(eq,app.VariableList.Name(idx),"val("+string(idx)+")"); %Always match the longest variable first

            % Evaluate the equations and update the values for multiple times
            for ii = 1:app.EquationDepth
                val(eqIdx) = arrayfun(@(x) evalEquation(x,val),eq);
            end   

            % Check and see if evaluation failed
            val(imag(val)~=0) = deal(NaN);
            val(isinf(val)) = deal(NaN);
            app.VariableList.EquationValue = val;
            invalidEqIdx = eqIdx(isnan(val(eqIdx)));

            if ~isempty(invalidEqIdx)
                [app.VarGrid.Children((invalidEqIdx-1)*nColumn + 4).BackgroundColor] = deal([0.85,0.33,0.10]);
                app.IsEquationCheck = false;
            else
                app.IsEquationCheck = true;
            end

            % Update values in gui
            validEqIdx = eqIdx(~isnan(val(eqIdx)));
            validVal = num2cell(val(validEqIdx));
            if ~isempty(validEqIdx)
                [app.VarGrid.Children((validEqIdx-1)*nColumn + 2).Value] = validVal{:};
            end
            if ~isempty(neqIdx)
                neVal = num2cell(val(neqIdx));
                [app.VarGrid.Children((neqIdx-1)*nColumn + 2).Value] = neVal{:};
            end

            function out = evalEquation(eq,val)
                try 
                    out = eval(eq);
                catch
                    out = NaN;
                end
            end
        end
        
        function buildScanList(app)
            enabledVarList = app.VariableList(app.VariableList.List~="None",:);
            listName = unique(enabledVarList.List);
            enabledListList = app.ListList(ismember(app.ListList.List,listName),:);
            sz = cellfun(@numel,enabledListList.ListValue);
            nRepeat = app.RepeatSpinner.Value;
            if isempty(sz)
                app.ScanList = [];
                return
            end
            app.ScanList = join(app.VariableList,app.ListList,'Keys',{'List','List'});

            if numel(sz) == 1 || app.ScanMethodButtonGroup.SelectedObject == app.DotButton
                if ~all(sz == sz(1))
                    error('In dot scan, the list sizes must be the same.')
                end
                nRun = sz(1);
            elseif numel(sz) == 2
                [list1,list2] = meshgrid(enabledListList.ListValue{1},enabledListList.ListValue{2});
                list1 = {list1(:).'};
                list2 = {list2(:).'};
                nRun = numel(list1{1});
                nList1 = size(app.ScanList(app.ScanList.List == enabledListList.List(1),:),1);
                nList2 = size(app.ScanList(app.ScanList.List == enabledListList.List(2),:),1);
                app.ScanList(app.ScanList.List == enabledListList.List(1),:).ListValue = repmat(list1,nList1,1);
                app.ScanList(app.ScanList.List == enabledListList.List(2),:).ListValue = repmat(list2,nList2,1);
            else
                error('Can not do cross scan with more than two lists.')
            end
            noneList = app.ScanList(app.ScanList.List == "None",:);
            app.ScanList(app.ScanList.List == "None",:).ListValue = ...
                mat2cell(repmat(noneList.Value,1,nRun),ones(1,numel(noneList.Value)),nRun);
            app.ScanList.ListValue = repmat(cell2mat(app.ScanList.ListValue),1,nRepeat);
            if app.AutoShuffleCheckBox.Value && string(app.ScanButton.Text) == "Stop"
                idx = randperm(nRepeat*nRun);
                app.ScanList.ListValue = app.ScanList.ListValue(:,idx);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HardwareList = loadVar("Config.mat","HardwareList");
            app.VariableList = loadVar("HardwareVariable.mat","VariableList");
            app.WaveformLibrary = loadVar("WaveformLibrary.mat","WaveformLibrary");
            app.WaveformGeneratorSetting = loadVar("WaveformGeneratorSetting.mat","WaveformGeneratorSetting");
            app.PhaseLockSetting = loadVar("PhaseLockSetting.mat","PhaseLockSetting");
            app.ScopeSetting = loadVar("ScopeSetting.mat","ScopeSetting");
            app.ListList = loadVar("HardwareVariable.mat","ListList");

            %% Initialize Variables
            for ii = 1:size(app.VariableList,1)
                app.addVar(app.VariableList.Name(ii),app.VariableList.Value(ii),app.VariableList.List(ii))
            end
            app.buildScanList
            app.evaluateEquation

            %% Initialize Waveform Generator
            wgList = app.HardwareList(app.HardwareList.Type =="WaveformGenerator",:);
            gl = uigridlayout(app.WgSubPanel1);
            gl.ColumnWidth = {'1x', '1x', '1x'};
            gl.RowHeight = repmat({20},1,size(wgList,1));
            gl.Scrollable = 'on';
            app.NWg = size(wgList,1);
            app.WgName = wgList.Name;
            for ii = 1:app.NWg
                uilabel(gl, 'Text',wgList.Name(ii));
                uibutton(gl, 'push','Text','Connect','ButtonPushedFcn',{@app.connectButtonPress,wgList.Name(ii)});
                uibutton(gl, 'push','Text','Show Window','ButtonPushedFcn',{@app.showWindowButtonPress,wgList.Name(ii)});
            end

            %% Initialize Scope
            sList = app.HardwareList(app.HardwareList.Type =="Scope",:);
            gl = uigridlayout(app.ScopeSubPanel1);
            gl.ColumnWidth = {'1x', '1x', '1x'};
            gl.RowHeight = repmat({20},1,size(sList,1));
            gl.Scrollable = 'on';
            app.NScope = size(sList,1);
            app.ScopeName = sList.Name;
            for ii = 1:app.NScope
                uilabel(gl, 'Text',sList.Name(ii));
                uibutton(gl, 'push','Text','Connect','ButtonPushedFcn',{@app.connectButtonPress,sList.Name(ii)});
                uibutton(gl, 'push','Text','Show Window','ButtonPushedFcn',{@app.showWindowButtonPress,sList.Name(ii)});
            end

            %% Initialize Phase Lock
            plList = app.HardwareList(app.HardwareList.Type =="PhaseLock",:);
            gl = uigridlayout(app.PlSubPanel1);
            gl.ColumnWidth = {'1x', '1x', '1x'};
            gl.RowHeight = repmat({20},1,size(plList,1));
            gl.Scrollable = 'on';
            app.NPl = size(plList,1);
            app.PlName = plList.Name;
            for ii = 1:app.NPl
                uilabel(gl, 'Text',plList.Name(ii));
                uibutton(gl, 'push','Text','Connect','ButtonPushedFcn',{@app.connectButtonPress,plList.Name(ii)});
                uibutton(gl, 'push','Text','Show Window','ButtonPushedFcn',{@app.showWindowButtonPress,plList.Name(ii)});
            end
        end

        % Button pushed function: AddVariableButton
        function AddVariableButtonPushed(app, event)
            n = numel(app.VarGrid.RowHeight);
            nameList = app.VariableList.Name;
            ii = 1;
            newName = app.VarPrefix + "var" + num2str(n+1);
            while any(nameList == newName)
                ii = ii + 1;
                newName = app.VarPrefix + "var" + num2str(n+ii);
            end
            app.addVar(newName,0,'None')
            app.VariableList(end+1,:) = table(newName,0,"None","",0,'VariableNames',{'Name','Value','List','Equation','EquationValue'});
            app.buildScanList
        end

        % Clicked callback: EditListDropDown
        function EditListDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if ~isempty(item)
                ListDialog(app,"List" + num2str(item))
            end
        end

        % Close request function: HardwareControlPanelUIFigure
        function HardwareControlPanelUIFigureCloseRequest(app, event)
            app.saveVariable;
            rWg = app.RunningWgControl;
            rScope = app.RunningScopeControl;
            rPl = app.RunningPhaseLockControl;
            if ~isempty(rWg)
                rWg = rWg(isvalid(rWg));
                for ii = 1:numel(rWg)
                    rWg(ii).disconnect;
                    delete(rWg(ii));
                end
            end
            if ~isempty(rScope)
                rScope = rScope(isvalid(rScope));
                for ii = 1:numel(rScope)
                    rScope(ii).disconnect;
                    delete(rScope(ii));
                end
            end
            if ~isempty(rPl)
                rPl = rPl(isvalid(rPl));
                for ii = 1:numel(rPl)
                    rPl(ii).disconnect;
                    delete(rPl(ii));
                end
            end
            app.saveSetting
            delete(app)
        end

        % Button pushed function: LockVariableButton
        function LockVariableButtonPushed(app, event)
            if string(app.LockVariableButton.Text) == "Lock Variable"
                app.lockVariable;
                app.LockVariableButton.Text = "Unlock Variable";
            else
                app.unlockVariable;
                app.LockVariableButton.Text = "Lock Variable";
            end
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            %% Set buttons
            if string(app.ScanButton.Text) == "Stop"
                app.ScanButton.Text = "Start";
                app.ScanButton.BackgroundColor = [0.3922 0.8314 0.0745];
                app.unlockVariable;
                app.UploadButton.Enable = "on";
                app.LockVariableButton.Enable = "on";
                app.ScanMethodButtonGroup.Enable = "on";
                app.TriggerSourceButtonGroup.Enable = "on";
                app.WaveformListEditorButton.Enable = "on";
                app.IsBecExpScanned = false;
                app.CurrentRunNumber = 1;
                app.enableButton;
                return
            end
            enabledVarList = app.VariableList(app.VariableList.List~="None",:);
            if isempty(enabledVarList)
                return
            end
            app.ScanButton.Text = 'Stop';
            app.ScanButton.BackgroundColor = [0.85,0.33,0.10];
            app.lockVariable;
            app.LockVariableButton.Enable = "off";
            app.ScanMethodButtonGroup.Enable = "off";
            app.TriggerSourceButtonGroup.Enable = "off";
            app.WaveformListEditorButton.Enable = "off";
            app.disableButton;
            if app.TriggerSourceButtonGroup.SelectedObject == app.BecExpButton
                app.UploadButton.Enable = "off";
                app.IsBecExpScanned = true;
            end

            %% Build Scan
            app.buildScanList

            %% Upload the first one
            app.upload;
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            if string(app.ScanButton.Text) == "Stop" && app.TriggerSourceButtonGroup.SelectedObject == app.ManualButton
                if app.CurrentRunNumber < (size(app.ScanList.ListValue,2) - 1)
                    app.CurrentRunNumber = app.CurrentRunNumber + 1;
                    app.upload;
                elseif app.CurrentRunNumber == (size(app.ScanList.ListValue,2) - 1)
                    app.CurrentRunNumber = app.CurrentRunNumber + 1;
                    app.upload;
                    app.ScanButtonPushed
                end
            else
                app.upload;
            end
        end

        % Button pushed function: WaveformListEditorButton
        function WaveformListEditorButtonPushed(app, event)
            WaveformListEditor(app);
        end

        % Button pushed function: ConnectAllButton
        function ConnectAllButtonPushed(app, event)
            if string(app.ScanButton.Text) == "Stop"
                return
            end
            wgName = app.WgName;
            if string(app.ConnectAllButton.Text) == "Connect All"
                for ii = 1:app.NWg
                    if isempty(app.RunningWgControl) || all([app.RunningWgControl.Name] ~= wgName(ii))
                        app.connectButtonPress(app.WgSubPanel1.Children.Children(ii*3-1),1,wgName(ii))
                    end
                end
                app.ConnectAllButton.Text = "Disconnect All";
            else
                for ii = 1:app.NWg
                    if (~isempty(app.RunningWgControl)) && any([app.RunningWgControl.Name] == wgName(ii))
                        app.connectButtonPress(app.WgSubPanel1.Children.Children(ii*3-1),1,wgName(ii))
                    end
                end
                app.ConnectAllButton.Text = "Connect All";
            end
        end

        % Button pushed function: ConnectAllButton_3
        function ConnectAllButton_3Pushed(app, event)
            sName = app.ScopeName;
            if string(app.ConnectAllButton_3.Text) == "Connect All"
                for ii = 1:app.NScope
                    if isempty(app.RunningScopeControl) || all([app.RunningScopeControl.Name] ~= sName(ii))
                        app.connectButtonPress(app.ScopeSubPanel1.Children.Children(ii*3-1),1,sName(ii))
                    end
                end
                app.ConnectAllButton_3.Text = "Disconnect All";
            else
                for ii = 1:app.NScope
                    if (~isempty(app.RunningScopeControl)) && any([app.RunningScopeControl.Name] == sName(ii))
                        app.connectButtonPress(app.ScopeSubPanel1.Children.Children(ii*3-1),1,sName(ii))
                    end
                end
                app.ConnectAllButton_3.Text = "Connect All";
            end
        end

        % Button pushed function: ShowAllButton
        function ShowAllButtonPushed(app, event)
            for ii = 1:app.NWg
                app.showWindowButtonPress(1,1,app.WgName(ii));
            end
        end

        % Button pushed function: ShowAllButton_3
        function ShowAllButton_3Pushed(app, event)
            for ii = 1:app.NScope
                app.showWindowButtonPress(1,1,app.ScopeName(ii));
            end
        end

        % Button pushed function: ConnectAllButton_4
        function ConnectAllButton_4Pushed(app, event)
            plName = app.PlName;
            if string(app.ConnectAllButton_4.Text) == "Connect All"
                for ii = 1:app.NPl
                    if isempty(app.RunningPhaseLockControl) || all([app.RunningPhaseLockControl.Name] ~= plName(ii))
                        app.connectButtonPress(app.PlSubPanel1.Children.Children(ii*3-1),1,plName(ii))
                    end
                end
                app.ConnectAllButton_4.Text = "Disconnect All";
            else
                for ii = 1:app.NPl
                    if (~isempty(app.RunningPhaseLockControl)) && any([app.RunningPhaseLockControl.Name] == plName(ii))
                        app.connectButtonPress(app.PlSubPanel1.Children.Children(ii*3-1),1,plName(ii))
                    end
                end
                app.ConnectAllButton_4.Text = "Connect All";
            end
        end

        % Button pushed function: ShowAllButton_4
        function ShowAllButton_4Pushed(app, event)
            for ii = 1:app.NPl
                app.showWindowButtonPress(1,1,app.PlName(ii));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HardwareControlPanelUIFigure = uifigure('Visible', 'off');
            app.HardwareControlPanelUIFigure.Name = 'Hardware Control Panel';
            app.HardwareControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @HardwareControlPanelUIFigureCloseRequest, true);
            app.HardwareControlPanelUIFigure.Tag = 'HwControlPanel';
            app.VariablesPanel = uipanel(app.HardwareControlPanelUIFigure);
            app.VariablesPanel.Title = 'Variables';
            app.Panel_3 = uipanel(app.VariablesPanel);
            app.VarGrid = uigridlayout(app.Panel_3);
            app.AddVariableButton = uibutton(app.VariablesPanel, 'push');
            app.AddVariableButton.ButtonPushedFcn = createCallbackFcn(app, @AddVariableButtonPushed, true);
            app.AddVariableButton.Text = 'Add Variable';
            app.EditListLabel = uilabel(app.VariablesPanel);
            app.EditListLabel.Text = 'Edit List:';
            app.EditListDropDown = uidropdown(app.VariablesPanel);
            app.EditListDropDown.Items = {'1', '2', '3', '4'};
            app.EditListDropDown.ClickedFcn = createCallbackFcn(app, @EditListDropDownClicked, true);
            app.EditListDropDown.Value = '1';
            app.ScanMethodButtonGroup = uibuttongroup(app.VariablesPanel);
            app.ScanMethodButtonGroup.Title = 'Scan Method';
            app.DotButton = uiradiobutton(app.ScanMethodButtonGroup);
            app.DotButton.Text = 'Dot';
            app.DotButton.Value = true;
            app.CrossButton = uiradiobutton(app.ScanMethodButtonGroup);
            app.CrossButton.Text = 'Cross';
            app.LockVariableButton = uibutton(app.VariablesPanel, 'push');
            app.LockVariableButton.ButtonPushedFcn = createCallbackFcn(app, @LockVariableButtonPushed, true);
            app.LockVariableButton.Text = 'Lock Variable';
            app.ScanButton = uibutton(app.VariablesPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.TriggerSourceButtonGroup = uibuttongroup(app.VariablesPanel);
            app.TriggerSourceButtonGroup.Title = 'Trigger Source';
            app.BecExpButton = uiradiobutton(app.TriggerSourceButtonGroup);
            app.BecExpButton.Text = 'BecExp';
            app.BecExpButton.Value = true;
            app.ManualButton = uiradiobutton(app.TriggerSourceButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.UploadButton = uibutton(app.VariablesPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.AutoShuffleCheckBox = uicheckbox(app.VariablesPanel);
            app.AutoShuffleCheckBox.Text = 'Auto Shuffle';
            app.RepeatSpinnerLabel = uilabel(app.VariablesPanel);
            app.RepeatSpinnerLabel.Text = 'Repeat';
            app.RepeatSpinner = uispinner(app.VariablesPanel);
            app.RepeatSpinner.Limits = [1 Inf];
            app.RepeatSpinner.RoundFractionalValues = 'on';
            app.RepeatSpinner.Value = 1;
            app.ScopePanel = uipanel(app.HardwareControlPanelUIFigure);
            app.ScopePanel.Title = 'Scope';
            app.ScopeSubPanel2 = uipanel(app.ScopePanel);
            app.ConnectAllButton_3 = uibutton(app.ScopeSubPanel2, 'push');
            app.ConnectAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConnectAllButton_3Pushed, true);
            app.ConnectAllButton_3.Text = 'Connect All';
            app.ShowAllButton_3 = uibutton(app.ScopeSubPanel2, 'push');
            app.ShowAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowAllButton_3Pushed, true);
            app.ShowAllButton_3.Text = 'Show All';
            app.RFGeneratorPanel = uipanel(app.HardwareControlPanelUIFigure);
            app.RFGeneratorPanel.Title = 'RF Generator';
            app.RgSubPanel2 = uipanel(app.RFGeneratorPanel);
            app.ConnectAllButton_2 = uibutton(app.RgSubPanel2, 'push');
            app.ConnectAllButton_2.Text = 'Connect All';
            app.ShowAllButton_2 = uibutton(app.RgSubPanel2, 'push');
            app.ShowAllButton_2.Text = 'Show All';
            app.WaveformGeneratorPanel = uipanel(app.HardwareControlPanelUIFigure);
            app.WaveformGeneratorPanel.Title = 'Waveform Generator';
            app.WgSubPanel2 = uipanel(app.WaveformGeneratorPanel);
            app.ConnectAllButton = uibutton(app.WgSubPanel2, 'push');
            app.ConnectAllButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectAllButtonPushed, true);
            app.ConnectAllButton.Text = 'Connect All';
            app.ShowAllButton = uibutton(app.WgSubPanel2, 'push');
            app.ShowAllButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllButtonPushed, true);
            app.ShowAllButton.Text = 'Show All';
            app.WaveformListEditorButton = uibutton(app.WgSubPanel2, 'push');
            app.WaveformListEditorButton.ButtonPushedFcn = createCallbackFcn(app, @WaveformListEditorButtonPushed, true);
            app.WaveformListEditorButton.Text = 'Waveform List Editor';
            app.ScopeSubPanel1 = uipanel(app.HardwareControlPanelUIFigure);
            app.RgSubPanel1 = uipanel(app.HardwareControlPanelUIFigure);
            app.WgSubPanel1 = uipanel(app.HardwareControlPanelUIFigure);
            app.PhaseLock = uipanel(app.HardwareControlPanelUIFigure);
            app.PhaseLock.Title = 'Phase Lock';
            app.PlSubPanel2 = uipanel(app.PhaseLock);
            app.ConnectAllButton_4 = uibutton(app.PlSubPanel2, 'push');
            app.ConnectAllButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConnectAllButton_4Pushed, true);
            app.ConnectAllButton_4.Text = 'Connect All';
            app.ShowAllButton_4 = uibutton(app.PlSubPanel2, 'push');
            app.ShowAllButton_4.ButtonPushedFcn = createCallbackFcn(app, @ShowAllButton_4Pushed, true);
            app.ShowAllButton_4.Text = 'Show All';
            app.PlSubPanel1 = uipanel(app.HardwareControlPanelUIFigure);
            % Show the figure after all components are created
            app.HardwareControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HardwareControlPanel

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.HardwareControlPanelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.HardwareControlPanelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HardwareControlPanelUIFigure)
        end
    end
end