classdef PhaseLockControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhaseLockControlPanelUIFigure  matlab.ui.Figure
        CurrentTargetFrequencyHzLabel  matlab.ui.control.Label
        CheckButton                    matlab.ui.control.Button
        VariableDropDown               matlab.ui.control.DropDown
        DisconnectButton               matlab.ui.control.Button
        SetTargetFrequencyHzEditField  matlab.ui.control.NumericEditField
        FrequencyHzLabel               matlab.ui.control.Label
        LockButton                     matlab.ui.control.Button
        ResourceNameLabel              matlab.ui.control.Label
        ModelLabel                     matlab.ui.control.Label
        ManufacturerLabel              matlab.ui.control.Label
        NameLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        PhaseLockSetting
    end
    
    properties (Access = public)
        SrcApp
        PhaseLock
        Name string
    end
    
    methods (Access = public)
        
        function disconnect(app)
            if ~isempty(app.PhaseLock)
                app.PhaseLock.close
            end
            app.updateSrc
            delete(app)
        end

        function check(app)
            if ~isempty(app.PhaseLock)
                app.PhaseLock.check
                app.CurrentTargetFrequencyHzLabel.Text = "Current Target Frequency (Hz): " + num2str(app.PhaseLock.CurrentFrequency);
                if app.PhaseLock.Status
                    app.LockButton.Text = "Unlock";
                else
                    app.LockButton.Text = "Lock";
                end
            end
        end
        
        function lock(app)
            if ~isempty(app.SrcApp)
                if string(app.VariableDropDown.Value) ~= "None"
                    varList = app.SrcApp.CurrentVariableList;
                    app.PhaseLock.Frequency = varList(varList.Name == app.PhaseLock.VariableName,:).Value;
                else
                    app.PhaseLock.Frequency = app.SetTargetFrequencyHzEditField.Value;
                end
            end
            app.PhaseLock.lock
        end

        function unlock(app)
            app.PhaseLock.unlock
        end
        
        function updateDisplay(app)
            app.SetTargetFrequencyHzEditField.Value = app.PhaseLock.Frequency;
            app.VariableDropDown.Value = app.PhaseLock.VariableName;
            app.PhaseLockSetting(app.PhaseLockSetting.Name == app.Name,:).Frequency ...
                = app.PhaseLock.Frequency;
            app.PhaseLockSetting(app.PhaseLockSetting.Name == app.Name,:).VariableName ...
                = app.PhaseLock.VariableName;
        end
    end
    
    methods (Access = private)
        
        function savePlSetting(app)
            if isempty(app.SrcApp)
                PhaseLockSetting = app.PhaseLockSetting;
                save(which("PhaseLockSetting.mat"),"PhaseLockSetting");
            else
                app.updateSrc;
            end
        end
        
        function updateSrc(app)
            if ~isempty(app.SrcApp)
                app.SrcApp.PhaseLockSetting(app.PhaseLockSetting.Name == app.Name,:) = ...
                    app.PhaseLockSetting(app.PhaseLockSetting.Name == app.Name,:);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, name, srcApp)
            if nargin == 3
                app.SrcApp = srcApp;
                app.PhaseLockSetting = srcApp.PhaseLockSetting;
                app.DisconnectButton.Enable = 'off';
                app.LockButton.Enable = 'off';
            else
                app.PhaseLockSetting = loadVar("PhaseLockSetting.mat","PhaseLockSetting");
            end
            app.PhaseLock = getPl(name,false);
            app.PhaseLock.connect
            app.Name = app.PhaseLock.Name;
            freqLimit = app.PhaseLock.FrequencyLimit;
            if ~isempty(freqLimit)
                app.SetTargetFrequencyHzEditField.Limits = freqLimit;
            end
            setting = app.PhaseLockSetting(app.PhaseLockSetting.Name == name,:);
            if isempty(setting)
                % Assign default settings to the PL
                dm = (app.PhaseLock.Manufacturer + app.PhaseLock.Model);
                t = app.PhaseLockSetting([app.PhaseLockSetting.DeviceModel] == dm,:);
                if ~isempty(t)
                    setting = t(1,:);
                else
                    warning("Can not find saved settings for " + dm + ". Will assign saved settings from another device.")
                    setting = app.PhaseLockSetting(1,:);
                    setting.DeviceModel = dm;
                end
                setting.Name = app.PhaseLock.Name;
                app.PhaseLockSetting(end+1,:) = setting;
                if ~isempty(app.SrcApp)
                    app.SrcApp.PhaseLockSetting = app.PhaseLockSetting;
                end
            end
            app.PhaseLock.Frequency = setting.Frequency;
            app.PhaseLock.VariableName = setting.VariableName;

            %% Change labels
            app.NameLabel.Text = app.NameLabel.Text + app.Name;
            app.ManufacturerLabel.Text = app.ManufacturerLabel.Text + app.PhaseLock.Manufacturer;
            app.ModelLabel.Text = app.ModelLabel.Text + app.PhaseLock.Model;
            app.ResourceNameLabel.Text = app.ResourceNameLabel.Text + app.PhaseLock.ResourceName;
            app.SetTargetFrequencyHzEditField.Value = app.PhaseLock.Frequency;
            if isempty(app.SrcApp)
                app.VariableDropDown.Enable = "off";
            else
                app.VariableDropDown.Items = ["None";app.SrcApp.VariableList.Name];
                app.VariableDropDown.Value = app.PhaseLock.VariableName;
                if app.PhaseLock.VariableName ~= "None"
                    app.SetTargetFrequencyHzEditField.Enable = "off";
                end
            end
            app.check
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.savePlSetting;
            app.disconnect;
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            app.check;
        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            if ~isempty(app.PhaseLock)
                if string(app.LockButton.Text) == "Lock"
                    app.lock;
                    app.check
                else
                    app.unlock;
                    app.check
                end
            end
        end

        % Value changed function: SetTargetFrequencyHzEditField
        function SetTargetFrequencyHzEditFieldValueChanged(app, event)
            value = app.SetTargetFrequencyHzEditField.Value;
            app.PhaseLock.Frequency = value;
            app.PhaseLockSetting(app.PhaseLockSetting.Name == app.Name,:).Frequency ...
                = value;
            app.updateSrc;
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            value = string(app.VariableDropDown.Value);
            if value ~= "None"
                app.SetTargetFrequencyHzEditField.Enable = "off";
            else
                app.SetTargetFrequencyHzEditField.Enable = "on";
            end
            app.PhaseLock.VariableName = value;
            app.PhaseLockSetting(app.PhaseLockSetting.Name == app.Name,:).VariableName ...
                = value;
            app.updateSrc;
        end

        % Close request function: PhaseLockControlPanelUIFigure
        function PhaseLockControlPanelUIFigureCloseRequest(app, event)
            app.savePlSetting;
            if ~isempty(app.SrcApp)
                app.PhaseLockControlPanelUIFigure.Visible = 'off';
            else
                app.disconnect
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhaseLockControlPanelUIFigure = uifigure('Visible', 'off');
            app.PhaseLockControlPanelUIFigure.Name = 'MATLAB App';
            app.PhaseLockControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @PhaseLockControlPanelUIFigureCloseRequest, true);
            app.NameLabel = uilabel(app.PhaseLockControlPanelUIFigure);
            app.NameLabel.Text = 'Name: ';
            app.ManufacturerLabel = uilabel(app.PhaseLockControlPanelUIFigure);
            app.ManufacturerLabel.Text = 'Manufacturer: ';
            app.ModelLabel = uilabel(app.PhaseLockControlPanelUIFigure);
            app.ModelLabel.Text = 'Model: ';
            app.ResourceNameLabel = uilabel(app.PhaseLockControlPanelUIFigure);
            app.ResourceNameLabel.Text = 'Resource Name: ';
            app.LockButton = uibutton(app.PhaseLockControlPanelUIFigure, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Text = 'Lock';
            app.FrequencyHzLabel = uilabel(app.PhaseLockControlPanelUIFigure);
            app.FrequencyHzLabel.Text = 'Set Target Frequency (Hz):';
            app.SetTargetFrequencyHzEditField = uieditfield(app.PhaseLockControlPanelUIFigure, 'numeric');
            app.SetTargetFrequencyHzEditField.Limits = [0 Inf];
            app.SetTargetFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SetTargetFrequencyHzEditFieldValueChanged, true);
            app.DisconnectButton = uibutton(app.PhaseLockControlPanelUIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.VariableDropDown = uidropdown(app.PhaseLockControlPanelUIFigure);
            app.VariableDropDown.Items = {'None'};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Value = 'None';
            app.CheckButton = uibutton(app.PhaseLockControlPanelUIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.CurrentTargetFrequencyHzLabel = uilabel(app.PhaseLockControlPanelUIFigure);
            app.CurrentTargetFrequencyHzLabel.Text = 'Current Target Frequency (Hz):';
            % Show the figure after all components are created
            app.PhaseLockControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseLockControlPanel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhaseLockControlPanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhaseLockControlPanelUIFigure)
        end
    end
end