classdef WgControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaveformGeneratorControlPanelUIFigure  matlab.ui.Figure
        OutputLoadDropDown          matlab.ui.control.DropDown
        OutputLoadDropDownLabel     matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        DisconnectButton            matlab.ui.control.Button
        ChannelsPanel               matlab.ui.container.Panel
        ChannelGrid                 matlab.ui.container.GridLayout
        UploadButton                matlab.ui.control.Button
        TriggerButton               matlab.ui.control.Button
        OutputModeDropDown          matlab.ui.control.DropDown
        OutputModeLabel             matlab.ui.control.Label
        TriggerSlopeDropDown        matlab.ui.control.DropDown
        TriggerSlopeLabel           matlab.ui.control.Label
        TriggerSourceDropDown       matlab.ui.control.DropDown
        TriggerSourceDropDownLabel  matlab.ui.control.Label
        SamplingRateEditField       matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel  matlab.ui.control.Label
        MaxSamplingRateLabel        matlab.ui.control.Label
        MemoryLabel                 matlab.ui.control.Label
        ResourceNameLabel           matlab.ui.control.Label
        ModelLabel                  matlab.ui.control.Label
        ManufacturerLabel           matlab.ui.control.Label
        NameLabel                   matlab.ui.control.Label
    end


    properties (Access = private)
        WaveformLibrary WaveformList
        WaveformGeneratorSetting table
    end

    properties (Access = public)
        SrcApp HardwareControlPanel
        WaveformGenerator
        Name string
    end

    methods (Access = private)


        function saveWgSetting(app)
            if isempty(app.SrcApp)
                WaveformGeneratorSetting = app.WaveformGeneratorSetting;
                save(which("WaveformGeneratorSetting.mat"),"WaveformGeneratorSetting");
            else
                app.updateSrc;
            end
        end

        function updateSrc(app)
            if ~isempty(app.SrcApp)
                app.SrcApp.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:) = ...
                    app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:);
            end
        end

        function disableProperty(app)
            dP = app.WaveformGenerator.DisabledProperty;
            appProp = string(properties(app));
            for ii = 1:numel(dP)
                targetAppProp = appProp(contains(appProp,dP(ii)));
                for jj = 1:numel(targetAppProp)
                    try
                        app.(targetAppProp(jj)).Enable = "off";
                    catch
                    end
                end
            end
        end
    end

    methods (Access = public)
        function switchValueChanged(app,src,~,cNumber)
            app.WaveformGenerator.IsOutput(cNumber) = src.Value;
            app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:).IsOutput{1}(cNumber) ...
                = src.Value;
            app.updateSrc;
        end

        function channelWfDropDownValueChanged(app,src,~,cNumber)
            name = string(src.Value);
            if name ~= "None"
                if ~isempty(app.SrcApp)
                    app.WaveformLibrary = app.SrcApp.WaveformLibrary;
                end
                app.WaveformGenerator.WaveformList{cNumber} = app.WaveformLibrary([app.WaveformLibrary.Name] == name);
            else
                app.WaveformGenerator.WaveformList{cNumber} = [];
            end
            app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:).WaveformListName{1}(cNumber) ...
                = name;
            app.updateSrc;
        end


        function disconnect(app)
            if ~isempty(app.WaveformGenerator)
                app.WaveformGenerator.close
            end
            delete(app)
        end

        function upload(app)
            if ~isempty(app.SrcApp)
                app.WaveformLibrary = app.SrcApp.WaveformLibrary;
            else
                app.WaveformLibrary = loadVar("WaveformLibrary.mat","WaveformLibrary");
            end

            for ii = 1:app.WaveformGenerator.NChannel
                name = string(app.ChannelGrid.Children(3*ii).Value);
                if name ~= "None"
                    app.WaveformGenerator.WaveformList{ii} = app.WaveformLibrary([app.WaveformLibrary.Name] == name);
                else
                    app.WaveformGenerator.WaveformList{ii} = [];
                end
            end

            if ~isempty(app.SrcApp)
                varList = app.SrcApp.CurrentVariableList;
                for ii = 1:app.WaveformGenerator.NChannel
                    if ~isempty(app.WaveformGenerator.WaveformList{ii})
                        for jj = 1:numel(app.WaveformGenerator.WaveformList{ii}.WaveformOrigin)
                            wfo = app.WaveformGenerator.WaveformList{ii}.WaveformOrigin{jj};
                            scan = wfo.Scan;
                            for kk = 1:size(scan,1)
                                if ismember(scan.VariableName(kk),varList.Name)
                                    wfo.(scan.ParameterName(kk)) = varList(varList.Name == scan.VariableName(kk),:).Value;
                                end
                            end
                            if isa(wfo,"ModulatedWaveform")
                                modName = ["AmplitudeModulation","FrequencyModulation","PhaseModulation"];
                                for mm = 1:numel(modName)
                                    if ~isempty(wfo.(modName(mm)))
                                        for ll = 1:numel(wfo.(modName(mm)).WaveformOrigin)
                                            wfoMod = wfo.(modName(mm)).WaveformOrigin{ll};
                                            scan = wfoMod.Scan;
                                            for kk = 1:size(scan,1)
                                                if ismember(scan.VariableName(kk),varList.Name)
                                                    wfoMod.(scan.ParameterName(kk)) = varList(varList.Name == scan.VariableName(kk),:).Value;
                                                end
                                            end
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
            end
            % disp(app.WaveformGenerator.WaveformList{1}.WaveformOrigin{1})
            app.WaveformGenerator.set
            app.WaveformGenerator.upload
        end

        function disableButton(app)
            app.SamplingRateEditField.Enable = "off";
            app.TriggerSourceDropDown.Enable = "off";
            app.TriggerSlopeDropDown.Enable = "off";
            app.OutputModeDropDown.Enable = "off";
            app.OutputLoadDropDown.Enable = "off";
            app.ResetButton.Enable = "off";
            app.UploadButton.Enable = "off";
            app.TriggerButton.Enable = "off";
            app.DisconnectButton.Enable = "off";
            for ii = 1:app.WaveformGenerator.NChannel
                app.ChannelGrid.Children(3*ii).Enable = "off";
                app.ChannelGrid.Children(3*ii-1).Enable = "off";
            end
        end

        function enableButton(app)
            app.SamplingRateEditField.Enable = "on";
            app.TriggerSourceDropDown.Enable = "on";
            app.TriggerSlopeDropDown.Enable = "on";
            app.OutputModeDropDown.Enable = "on";
            app.OutputLoadDropDown.Enable = "on";
            app.ResetButton.Enable = "on";
            if isempty(app.SrcApp)
                app.UploadButton.Enable = "on";
                app.TriggerButton.Enable = "on";
                app.DisconnectButton.Enable = "on";
            end
            for ii = 1:app.WaveformGenerator.NChannel
                app.ChannelGrid.Children(3*ii).Enable = "on";
                app.ChannelGrid.Children(3*ii-1).Enable = "on";
            end
            app.disableProperty;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, name, srcApp)
            if nargin == 3
                app.SrcApp = srcApp;
                app.WaveformGeneratorSetting = srcApp.WaveformGeneratorSetting;
                app.WaveformLibrary = srcApp.WaveformLibrary;
                app.DisconnectButton.Enable = 'off';
                app.UploadButton.Enable = 'off';
            else
                app.WaveformGeneratorSetting = loadVar("WaveformGeneratorSetting.mat","WaveformGeneratorSetting");
                app.WaveformLibrary = loadVar("WaveformLibrary.mat","WaveformLibrary");
            end

            %% Load setting
            app.WaveformGenerator = getWg(name,false);
            % app.WaveformGenerator.connect
            app.Name = app.WaveformGenerator.Name;
            maxSr = app.WaveformGenerator.SamplingRate;
            app.MaxSamplingRateLabel.Text = app.MaxSamplingRateLabel.Text + string(maxSr);
            setting = app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == name,:);
            if isempty(setting)
                % Assign default settings to the AWG
                dm = (app.WaveformGenerator.Manufacturer + app.WaveformGenerator.Model);
                t = app.WaveformGeneratorSetting([app.WaveformGeneratorSetting.DeviceModel] == dm,:);
                if ~isempty(t)
                    setting = t(1,:);
                else
                    warning("Can not find saved settings for " + dm + ". Will assign saved settings from another device.")
                    setting = app.WaveformGeneratorSetting(1,:);
                    setting.DeviceModel = dm;
                end
                setting.Name = app.WaveformGenerator.Name;
                app.WaveformGeneratorSetting(end+1,:) = setting;
                if ~isempty(app.SrcApp)
                    app.SrcApp.WaveformGeneratorSetting = app.WaveformGeneratorSetting;
                end
            end
            app.WaveformGenerator.SamplingRate = setting.SamplingRate;
            app.WaveformGenerator.TriggerSource = setting.TriggerSource;
            app.WaveformGenerator.TriggerSlope = setting.TriggerSlope;
            app.WaveformGenerator.OutputMode = setting.OutputMode;
            app.WaveformGenerator.IsOutput = setting.IsOutput{1};
            app.WaveformGenerator.OutputLoad = setting.OutputLoad;

            %% Change labels
            app.NameLabel.Text = app.NameLabel.Text + app.Name;
            app.ManufacturerLabel.Text = app.ManufacturerLabel.Text + app.WaveformGenerator.Manufacturer;
            app.ModelLabel.Text = app.ModelLabel.Text + app.WaveformGenerator.Model;
            app.ResourceNameLabel.Text = {char(app.ResourceNameLabel.Text),char(app.WaveformGenerator.ResourceName)};
            app.MemoryLabel.Text = app.MemoryLabel.Text + string(app.WaveformGenerator.Memory);
            app.SamplingRateEditField.Limits = [1,maxSr];
            app.SamplingRateEditField.Value = app.WaveformGenerator.SamplingRate;
            app.TriggerSourceDropDown.Value = app.WaveformGenerator.TriggerSource;
            if app.TriggerSourceDropDown.Value ~= "Software"
                app.TriggerButton.Enable = "off";
            else
                app.TriggerButton.Enable = "on";
            end
            app.TriggerSlopeDropDown.Value = app.WaveformGenerator.TriggerSlope;
            app.OutputModeDropDown.Value = app.WaveformGenerator.OutputMode;
            app.OutputLoadDropDown.Value = app.WaveformGenerator.OutputLoad;

            wflName = [app.WaveformLibrary.Name];
            for ii = 1:app.WaveformGenerator.NChannel
                app.ChannelGrid.RowHeight{end + 1} = 25;
                uilabel(app.ChannelGrid,"Text","Ch" + num2str(ii));
                uiswitch(app.ChannelGrid, 'slider','ItemsData',[0,1],'Value',app.WaveformGenerator.IsOutput(ii),...
                    'ValueChangedFcn',{@app.switchValueChanged,ii});
                uidd = uidropdown(app.ChannelGrid,"Items",["None",wflName],"ValueChangedFcn",{@app.channelWfDropDownValueChanged,ii});
                uidd2 = uidropdown(app.ChannelGrid,"Items",["External","Immediate"]);
                if ~isempty(setting.WaveformListName{1})
                    if ismember(setting.WaveformListName{1},string(uidd.Items))
                        uidd.Value = setting.WaveformListName{1}(ii);
                    else
                        uidd.Value = "None";
                    end
                else
                    uidd.Value = "None";
                end
                app.channelWfDropDownValueChanged(uidd,1,ii)
            end

            %% Diable properties
            app.disableProperty;
        end

        % Value changed function: SamplingRateEditField
        function SamplingRateEditFieldValueChanged(app, event)
            app.WaveformGenerator.SamplingRate = app.SamplingRateEditField.Value;
            app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:).SamplingRate ...
                = app.SamplingRateEditField.Value;
            app.updateSrc;
        end

        % Value changed function: TriggerSourceDropDown
        function TriggerSourceDropDownValueChanged(app, event)
            app.WaveformGenerator.TriggerSource = app.TriggerSourceDropDown.Value;
            app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:).TriggerSource ...
                = app.TriggerSourceDropDown.Value;
            if app.TriggerSourceDropDown.Value ~= "Software"
                app.TriggerButton.Enable = "off";
            else
                app.TriggerButton.Enable = "on";
            end
            app.updateSrc;
        end

        % Value changed function: TriggerSlopeDropDown
        function TriggerSlopeDropDownValueChanged(app, event)
            app.WaveformGenerator.TriggerSlope = app.TriggerSlopeDropDown.Value;
            app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:).TriggerSlope ...
                = app.TriggerSlopeDropDown.Value;
            app.updateSrc;
        end

        % Value changed function: OutputModeDropDown
        function OutputModeDropDownValueChanged(app, event)
            app.WaveformGenerator.OutputMode = app.OutputModeDropDown.Value;
            app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:).OutputMode ...
                = app.OutputModeDropDown.Value;
            app.updateSrc;
        end

        % Close request function: WaveformGeneratorControlPanelUIFigure
        function WaveformGeneratorControlPanelUIFigureCloseRequest(app, event)
            app.saveWgSetting;
            if ~isempty(app.SrcApp)
                app.WaveformGeneratorControlPanelUIFigure.Visible = 'off';
            else
                app.disconnect
            end
        end

        % Value changed function: OutputLoadDropDown
        function OutputLoadDropDownValueChanged(app, event)
            app.WaveformGenerator.OutputLoad = app.OutputLoadDropDown.Value;
            app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:).OutputLoad ...
                = app.OutputLoadDropDown.Value;
            app.updateSrc;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.saveWgSetting;
            app.disconnect
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.upload;
            app.saveWgSetting;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.WaveformGenerator.set;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaveformGeneratorControlPanelUIFigure = uifigure('Visible', 'off');
            app.WaveformGeneratorControlPanelUIFigure.Name = 'Waveform Generator Control Panel';
            app.WaveformGeneratorControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @WaveformGeneratorControlPanelUIFigureCloseRequest, true);
            app.WaveformGeneratorControlPanelUIFigure.Tag = 'WgControlPanel';
            app.NameLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.NameLabel.Text = 'Name: ';
            app.ManufacturerLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.ManufacturerLabel.Text = 'Manufacturer: ';
            app.ModelLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.ModelLabel.Text = 'Model: ';
            app.ResourceNameLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.ResourceNameLabel.Text = 'Resource Name: ';
            app.MemoryLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.MemoryLabel.Text = 'Memory: ';
            app.MaxSamplingRateLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.MaxSamplingRateLabel.Text = 'Max Sampling Rate: ';
            app.SamplingRateEditFieldLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate: ';
            app.SamplingRateEditField = uieditfield(app.WaveformGeneratorControlPanelUIFigure, 'numeric');
            app.SamplingRateEditField.LowerLimitInclusive = 'off';
            app.SamplingRateEditField.Limits = [0 Inf];
            app.SamplingRateEditField.RoundFractionalValues = 'on';
            app.SamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditFieldValueChanged, true);
            app.SamplingRateEditField.Value = 1000;
            app.TriggerSourceDropDownLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.TriggerSourceDropDownLabel.Text = 'Trigger Source:';
            app.TriggerSourceDropDown = uidropdown(app.WaveformGeneratorControlPanelUIFigure);
            app.TriggerSourceDropDown.Items = {'External', 'Software', 'Immediate'};
            app.TriggerSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerSourceDropDownValueChanged, true);
            app.TriggerSourceDropDown.Value = 'External';
            app.TriggerSlopeLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.TriggerSlopeLabel.Text = 'Trigger Slope:';
            app.TriggerSlopeDropDown = uidropdown(app.WaveformGeneratorControlPanelUIFigure);
            app.TriggerSlopeDropDown.Items = {'Rise', 'Fall'};
            app.TriggerSlopeDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerSlopeDropDownValueChanged, true);
            app.TriggerSlopeDropDown.Value = 'Rise';
            app.OutputModeLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.OutputModeLabel.Text = 'Output Mode:';
            app.OutputModeDropDown = uidropdown(app.WaveformGeneratorControlPanelUIFigure);
            app.OutputModeDropDown.Items = {'Normal', 'Gated'};
            app.OutputModeDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputModeDropDownValueChanged, true);
            app.OutputModeDropDown.Value = 'Normal';
            app.TriggerButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.TriggerButton.Text = 'Trigger';
            app.UploadButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ChannelsPanel = uipanel(app.WaveformGeneratorControlPanelUIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.ChannelGrid = uigridlayout(app.ChannelsPanel);
            app.DisconnectButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ResetButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OutputLoadDropDownLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.OutputLoadDropDownLabel.Text = 'Output Load:';
            app.OutputLoadDropDown = uidropdown(app.WaveformGeneratorControlPanelUIFigure);
            app.OutputLoadDropDown.Items = {'50', 'Infinity'};
            app.OutputLoadDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputLoadDropDownValueChanged, true);
            app.OutputLoadDropDown.Value = '50';
            % Show the figure after all components are created
            app.WaveformGeneratorControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WgControlPanel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaveformGeneratorControlPanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaveformGeneratorControlPanelUIFigure)
        end
    end
end