classdef WaveformListEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WaveformPanel2                matlab.ui.container.Panel
        WaveformGrid                  matlab.ui.container.GridLayout
        PatchConstantEditField        matlab.ui.control.NumericEditField
        PatchConstantEditField_Label  matlab.ui.control.Label
        PatchMethodDropDown           matlab.ui.control.DropDown
        PatchMethodDropDown_Label     matlab.ui.control.Label
        ConcatMethodDropDown          matlab.ui.control.DropDown
        ConcatMethodDropDown_Label    matlab.ui.control.Label
        WaveformListNameDropDown      matlab.ui.control.DropDown
        WaveformListNameLabel         matlab.ui.control.Label
        TriggerAdvanceCheckBox        matlab.ui.control.CheckBox
        SaveWaveformListButton        matlab.ui.control.Button
        AddWaveformStepButton         matlab.ui.control.Button
    end


    properties (Access = public)
        SrcApp % Description
        WaveformLibrary
    end

    properties (Constant)
        WaveformTypeList = {'ConstantWave','TrapezoidalPulse','LinearRamp',...
            'SineWave','TrapezoidalSinePulse','TanhSinePulse','PchipSinePulse',...
            'LinearFrequencyRamp','SineWaveModulated'}
    end

    methods (Access = private)

        function loadWaveformListProp(app)
            wfl = app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value));
            if ~isempty(wfl)
                app.ConcatMethodDropDown.Value = wfl.ConcatMethod;
                app.PatchMethodDropDown.Value = wfl.PatchMethod;
                app.PatchConstantEditField.Value = wfl.PatchConstant;
                app.TriggerAdvanceCheckBox.Value = wfl.IsTriggerAdvance;
                for ii = 1:numel(wfl.WaveformOrigin)
                    app.addWaveformStep(string(class(wfl.WaveformOrigin{ii})))
                end
            end
            app.togglePatch;
        end

        function addWaveformStep(app,typeName)
            app.WaveformGrid.RowHeight{end + 1} = 20;
            tpList = uidropdown(app.WaveformGrid,'Items',app.WaveformTypeList,...
                'ValueChangedFcn',@app.typeDropDownChanged);
            if nargin == 2
                tpList.Value = typeName;
            end
            uibutton(app.WaveformGrid, 'push','Text','Edit','ButtonPushedFcn',@app.editButtonPushed);
            uibutton(app.WaveformGrid, 'push','Text','Delete','ButtonPushedFcn',@app.deleteButtonPushed);
        end

        function typeDropDownChanged(app,src,~)
            row = src.Layout.Row;
            typeName = src.Value;
            listName = string(app.WaveformListNameDropDown.Value);
            wfl = app.WaveformLibrary([app.WaveformLibrary.Name] == listName);
            wfo = wfl.WaveformOrigin{row};
            sr = wfo.SamplingRate;
            st = wfo.StartTime;
            du = wfo.Duration;
            amp = wfo.Amplitude;
            os = wfo.Offset;
            wfl.WaveformOrigin{row} = feval(typeName);
            wfo = wfl.WaveformOrigin{row};
            wfo.SamplingRate = sr;
            wfo.StartTime = st;
            wfo.Duration = du;
            wfo.Amplitude = amp;
            wfo.Offset = os;
        end

        function editButtonPushed(app,src,~)
            row = src.Layout.Row;
            wf = app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).WaveformOrigin{row};
            WaveformEditor(wf,app)
        end

        function deleteButtonPushed(app,src,~)
            nRow = numel(app.WaveformGrid.RowHeight);
            nColumn = 3;
            row = src.Layout.Row;
            for ii = (row+1):nRow
                for jj = 1:nColumn
                    app.WaveformGrid.Children((ii-1)*nColumn + jj).Layout.Row = ...
                        app.WaveformGrid.Children((ii-1)*nColumn + jj).Layout.Row - 1;
                end
            end
            permuteIdx = [1:(row-1)*nColumn,(row*nColumn+1):nRow*nColumn,((row-1)*nColumn+1):row*nColumn];
            app.WaveformGrid.Children = app.WaveformGrid.Children(permuteIdx);
            delete(app.WaveformGrid.Children((end-nColumn+1) : end))
            app.WaveformGrid.RowHeight(end) = [];
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).WaveformOrigin(row) = [];
        end

        function saveWaveform(app)
            if ~isempty(app.SrcApp)
                if string(app.SrcApp.ScanButton.Text) == "Stop"
                    return
                end
            end
            WaveformLibrary = app.WaveformLibrary;
            save(which("WaveformLibrary.mat"),"WaveformLibrary");
            if ~isempty(app.SrcApp)
                app.SrcApp.WaveformLibrary = WaveformLibrary;
            end
        end

        
        function togglePatch(app)
            if string(app.ConcatMethodDropDown.Value) == "Sequential"
                app.PatchMethodDropDown.Enable = "off";
                app.PatchConstantEditField.Enable = "off";
            else
                app.PatchMethodDropDown.Enable = "on";
                app.PatchConstantEditField.Enable = "on";
            end
        end
    end
    
    methods (Access = public)
        
        function disableButton(app)
            if isempty(app.SrcApp) || string(app.SrcApp.ScanButton.Text) == "Scan"
                return
            end
            for ii = 1:numel(app.UIFigure.Children)
                if app.UIFigure.Children(ii) ~= app.WaveformListNameDropDown
                    app.UIFigure.Children(ii).Enable = "off";
                end
            end
        end

        function enableButton(app)
            if app.UIFigure.Children(ii) ~= app.WaveformListNameDropDown
                app.UIFigure.Children(ii).Enable = "on";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, srcApp)
            if nargin == 2
                app.SrcApp = srcApp;
                app.WaveformLibrary = srcApp.WaveformLibrary;
            else
                app.WaveformLibrary = loadVar("WaveformLibrary.mat","WaveformLibrary");
            end
            wflName = [app.WaveformLibrary.Name];
            app.WaveformListNameDropDown.Items = [wflName,"New List"];
            app.loadWaveformListProp;
        end

        % Value changed function: WaveformListNameDropDown
        function WaveformListNameDropDownValueChanged(app, event)
            listName = string(app.WaveformListNameDropDown.Value);
            delete(app.WaveformGrid.Children(:))
            app.WaveformGrid.RowHeight = {};
            if listName ~= "New List"
                app.loadWaveformListProp
            else
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                listName = inputdlg("Please enter the name of the new Waveform List.","New Waveform List");
                delete(f);
                if ~isempty(listName)
                    if ~ismember(listName,[app.WaveformLibrary.Name])
                        app.WaveformLibrary(end + 1) = WaveformList(listName);
                        wflName = [app.WaveformLibrary.Name];
                        app.WaveformListNameDropDown.Items = [wflName,"New List"];
                        app.WaveformListNameDropDown.Value = listName;
                        wgApp = get(findall(0, 'Tag', "WgControlPanel"), 'RunningAppInstance');
                        for ii = 1:numel(wgApp)
                            if iscell(wgApp)
                                wg = wgApp{ii};
                            else
                                wg = wgApp(ii);
                            end
                            cDropDown = wg.ChannelGrid.Children(3:3:end);
                            for jj = 1:numel(cDropDown)
                                cDropDown(jj).Items = ["None",wflName];
                            end
                        end
                    else
                        msgbox("The name has been used.")
                        app.WaveformListNameDropDown.Value = app.WaveformListNameDropDown.Items{1};
                    end
                else
                    app.WaveformListNameDropDown.Value = app.WaveformListNameDropDown.Items{1};
                end
                app.loadWaveformListProp;
            end

        end

        % Button pushed function: AddWaveformStepButton
        function AddWaveformStepButtonPushed(app, event)
            app.addWaveformStep("ConstantWave")
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).WaveformOrigin{end + 1} = ConstantWave;
        end

        % Value changed function: ConcatMethodDropDown
        function ConcatMethodDropDownValueChanged(app, event)
            value = app.ConcatMethodDropDown.Value;
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).ConcatMethod = value;
            app.togglePatch;
        end

        % Value changed function: PatchMethodDropDown
        function PatchMethodDropDownValueChanged(app, event)
            value = app.PatchMethodDropDown.Value;
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).PatchMethod = value;
        end

        % Value changed function: PatchConstantEditField
        function PatchConstantEditFieldValueChanged(app, event)
            value = app.PatchConstantEditField.Value;
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).PatchConstant = value;
        end

        % Value changed function: TriggerAdvanceCheckBox
        function TriggerAdvanceCheckBoxValueChanged(app, event)
            value = app.TriggerAdvanceCheckBox.Value;
            app.WaveformLibrary([app.WaveformLibrary.Name] == string(app.WaveformListNameDropDown.Value)).IsTriggerAdvance = value;
        end

        % Button pushed function: SaveWaveformListButton
        function SaveWaveformListButtonPushed(app, event)
            app.saveWaveform;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.saveWaveform;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddWaveformStepButton = uibutton(app.UIFigure, 'push');
            app.AddWaveformStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddWaveformStepButtonPushed, true);
            app.AddWaveformStepButton.Text = 'Add Waveform Step';
            app.SaveWaveformListButton = uibutton(app.UIFigure, 'push');
            app.SaveWaveformListButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWaveformListButtonPushed, true);
            app.SaveWaveformListButton.Text = 'Save Waveform List';
            app.TriggerAdvanceCheckBox = uicheckbox(app.UIFigure);
            app.TriggerAdvanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @TriggerAdvanceCheckBoxValueChanged, true);
            app.TriggerAdvanceCheckBox.Text = 'Trigger Advance';
            app.WaveformListNameLabel = uilabel(app.UIFigure);
            app.WaveformListNameLabel.Text = 'Waveform List Name:';
            app.WaveformListNameDropDown = uidropdown(app.UIFigure);
            app.WaveformListNameDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformListNameDropDownValueChanged, true);
            app.ConcatMethodDropDown_Label = uilabel(app.UIFigure);
            app.ConcatMethodDropDown_Label.Text = 'Concat Method:';
            app.ConcatMethodDropDown = uidropdown(app.UIFigure);
            app.ConcatMethodDropDown.Items = {'Sequential', 'Simultaneous'};
            app.ConcatMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ConcatMethodDropDownValueChanged, true);
            app.ConcatMethodDropDown.Value = 'Sequential';
            app.PatchMethodDropDown_Label = uilabel(app.UIFigure);
            app.PatchMethodDropDown_Label.Text = 'Patch Method:';
            app.PatchMethodDropDown = uidropdown(app.UIFigure);
            app.PatchMethodDropDown.Items = {'Continue', 'Constant'};
            app.PatchMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PatchMethodDropDownValueChanged, true);
            app.PatchMethodDropDown.Value = 'Continue';
            app.PatchConstantEditField_Label = uilabel(app.UIFigure);
            app.PatchConstantEditField_Label.Text = 'Patch Constant:';
            app.PatchConstantEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatchConstantEditField.ValueChangedFcn = createCallbackFcn(app, @PatchConstantEditFieldValueChanged, true);
            app.WaveformPanel2 = uipanel(app.UIFigure);
            app.WaveformGrid = uigridlayout(app.WaveformPanel2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveformListEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end