classdef WaveformAssociation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaveformAssociationUIFigure  matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
    end


    properties (Access = public)
        SrcApp % Description
        WgSettings
        WflName
        NRow
    end

    properties (Constant)
        NColumn = 4
    end
    
    methods (Access = private)
        
        function t = grid2table(app)
            nRow = numel(app.GridLayout.RowHeight);
            if isempty(nRow)
                t = table.empty;
            else
                WaveformGeneratorName = strings(nRow,1);
                ChannelName = strings(nRow,1);
                WaveformListName = strings(nRow,1);
                for ii = 1:nRow
                    WaveformGeneratorName(ii) = app.GridLayout.Children(ii*(nRow-1)+1).Value;
                    ChannelName(ii) = app.GridLayout.Children(ii*(nRow-1)+2).Value;
                    WaveformListName(ii) = app.GridLayout.Children(ii*(nRow-1)+3).Value;
                end
                t = table(WaveformGeneratorName,ChannelName,WaveformListName);
            end
        end
        
        function checkboxValueChanged(app,src,~,rowNumber)
            nColumn = app.NColumn;
            if ~src.Value
                for ii = 2:nColumn
                    app.GridLayout.Children((rowNumber-1)*nColumn+ii).Enable = "off";
                end
            else
                for ii = 2:nColumn
                    app.GridLayout.Children((rowNumber-1)*nColumn+ii).Enable = "on";
                end
            end
        end
        
        function disableRow(app,WgName,ChName)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, srcApp)
            app.WgSettings = loadVar("WaveformGeneratorSetting","WaveformGeneratorSetting");
            load WaveformLibrary.mat WaveformLibrary
            app.WflName = [WaveformLibrary.Name];
            app.NRow = sum(cellfun(@numel,app.WgSettings.IsOutput));
            rowNumber = 1;
            for ii = 1:size(app.WgSettings,1)
                wfs = app.WgSettings(ii,:);
                nch = numel(wfs.IsOutput{1});
                for jj = 1:nch
                    app.GridLayout.RowHeight{end + 1} = 25;
                    uicheckbox(app.GridLayout,"Text","","ValueChangedFcn",{@app.checkboxValueChanged,rowNumber},"Value",false);
                    uilabel(app.GridLayout,"Text",wfs.Name,"Enable","off");
                    uilabel(app.GridLayout,"Text","Ch"+string(jj),"Enable","off");
                    uidropdown(app.GridLayout,"Items",["None",app.WflName],'Value',wfs.WaveformListName{1}(jj),"Enable","off");
                    rowNumber = rowNumber + 1;
                end
            end
            
            if nargin == 2
                nColumn = app.NColumn;
                app.SrcApp = srcApp;
                wa = srcApp.TrialConfig.WaveformAssociation;
                if ~isempty(wa) && ~ismissing(wa) && wa~="None"
                    wat = str2table(wa);
                    allWfNameLabel = string({app.GridLayout.Children(2:nColumn:end).Text});
                    allChNameLabel = string({app.GridLayout.Children(3:nColumn:end).Text});
                    for ii = 1:size(wat,1)
                        rowNumber = find(allWfNameLabel == wat(ii,:).WaveformGeneratorName & allChNameLabel == wat(ii,:).ChannelName);
                        app.GridLayout.Children((rowNumber-1)*nColumn+1).Value = true;
                        for jj = 2:nColumn
                            app.GridLayout.Children((rowNumber-1)*nColumn+jj).Enable = "on";
                        end
                        app.GridLayout.Children((rowNumber-1)*nColumn+4).Value = wat(ii,:).WaveformListName;
                    end
                end
            end

        end

        % Close request function: WaveformAssociationUIFigure
        function WaveformAssociationUIFigureCloseRequest(app, event)
            if ~isempty(app.SrcApp)
                nColumn = app.NColumn;
                isEnable = [app.GridLayout.Children(1:nColumn:end).Value];
                if any(isEnable)
                    nRow = sum(isEnable);
                    WaveformGeneratorName = strings(nRow,1);
                    ChannelName = strings(nRow,1);
                    WaveformListName = strings(nRow,1);
                    rowList = find(isEnable);
                    for ii = 1:numel(rowList)
                        WaveformGeneratorName(ii) = string(app.GridLayout.Children((rowList(ii)-1)*nColumn+2).Text);
                        ChannelName(ii) = string(app.GridLayout.Children((rowList(ii)-1)*nColumn+3).Text);
                        WaveformListName(ii) = string(app.GridLayout.Children((rowList(ii)-1)*nColumn+4).Value);
                    end
                    s = table2str(table(WaveformGeneratorName,ChannelName,WaveformListName));
                else
                    s = string(missing);
                end
                app.SrcApp.TrialConfig.WaveformAssociation = s;
                if ~isempty(app.SrcApp.BecExp)
                    if isvalid(app.SrcApp.BecExp)
                        app.SrcApp.BecExp.WaveformAssociation = s;
                    end
                end
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaveformAssociationUIFigure = uifigure('Visible', 'off');
            app.WaveformAssociationUIFigure.Name = 'WaveformAssociation';
            app.WaveformAssociationUIFigure.CloseRequestFcn = createCallbackFcn(app, @WaveformAssociationUIFigureCloseRequest, true);
            app.WaveformAssociationUIFigure.Tag = 'WaveformAssociation';
            app.Panel = uipanel(app.WaveformAssociationUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            % Show the figure after all components are created
            app.WaveformAssociationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveformAssociation(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.WaveformAssociationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.WaveformAssociationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaveformAssociationUIFigure)
        end
    end
end